classdef SLAM_PoseEstimation_Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        InSt_ResultsPanel               matlab.ui.container.Panel
        EnterfulldestinationpathEditField_3  matlab.ui.control.EditField
        EnterfulldestinationpathEditField_3Label  matlab.ui.control.Label
        SaveButton_3                    matlab.ui.control.Button
        SavingFeaturesMapLabel          matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        axisasshownLabel                matlab.ui.control.Label
        firstkeyframewithreferenceLabel  matlab.ui.control.Label
        CurrentPoseisreferencedtoLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ReferenceframeLabel             matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        zdegEditField                   matlab.ui.control.NumericEditField
        zdegEditFieldLabel              matlab.ui.control.Label
        ydegEditField                   matlab.ui.control.NumericEditField
        ydegEditFieldLabel              matlab.ui.control.Label
        xdegEditField                   matlab.ui.control.NumericEditField
        xdegEditFieldLabel              matlab.ui.control.Label
        PzmEditField                    matlab.ui.control.NumericEditField
        PzmEditFieldLabel               matlab.ui.control.Label
        PymEditField                    matlab.ui.control.NumericEditField
        PymEditFieldLabel               matlab.ui.control.Label
        PxmEditField                    matlab.ui.control.NumericEditField
        PxmEditFieldLabel               matlab.ui.control.Label
        CurrentPoseLabel                matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        TotalnumberofmappointsEditField  matlab.ui.control.EditField
        TotalnumberofmappointsEditFieldLabel  matlab.ui.control.Label
        TotalnumberofkeyframesEditField  matlab.ui.control.EditField
        TotalnumberofkeyframesEditFieldLabel  matlab.ui.control.Label
        VSLAMStatusEditField            matlab.ui.control.EditField
        VSLAMStatusEditFieldLabel       matlab.ui.control.Label
        OutSt_ResultsPanel              matlab.ui.container.Panel
        Label_20                        matlab.ui.control.Label
        EnterfulldestinationpathEditField_2  matlab.ui.control.EditField
        EnterfulldestinationpathEditField_2Label  matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        SaveGeoAxesLocallyLabel_2       matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        DifferenceinAltitudemEditField_2  matlab.ui.control.NumericEditField
        DifferenceinAltitudemEditField_2Label  matlab.ui.control.Label
        AveragevelocitymsEditField_2    matlab.ui.control.NumericEditField
        AveragevelocitymsEditField_2Label  matlab.ui.control.Label
        TotalTimesecEditField_2         matlab.ui.control.NumericEditField
        TotalTimesecEditField_2Label    matlab.ui.control.Label
        TotalPlanardistancemEditField_2  matlab.ui.control.NumericEditField
        TotalPlanardistancemEditField_2Label  matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        DistanceLabel_2                 matlab.ui.control.Label
        CurrentHeadingangleClockwiseLabel_2  matlab.ui.control.Label
        fromNorthdegLabel_2             matlab.ui.control.Label
        headingEditField_2              matlab.ui.control.NumericEditField
        UIAxes2_3                       matlab.ui.control.UIAxes
        OutLog_ResultsPanel             matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        EnterfulldestinationpathEditField  matlab.ui.control.EditField
        EnterfulldestinationpathEditFieldLabel  matlab.ui.control.Label
        SaveGeoAxesLocallyLabel         matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        headingEditField                matlab.ui.control.NumericEditField
        fromNorthdegLabel               matlab.ui.control.Label
        CurrentHeadingangleClockwiseLabel  matlab.ui.control.Label
        DifferenceinAltitudemEditField  matlab.ui.control.NumericEditField
        DifferenceinAltitudemLabel      matlab.ui.control.Label
        AveragevelocitymsEditField      matlab.ui.control.NumericEditField
        AveragevelocitymsLabel          matlab.ui.control.Label
        TotalTimesecEditField           matlab.ui.control.NumericEditField
        TotalTimesecLabel               matlab.ui.control.Label
        TotalPlanardistancemEditField   matlab.ui.control.NumericEditField
        TotalPlanardistancemLabel       matlab.ui.control.Label
        DistanceLabel                   matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        InSt_mid2_ResultsPanel          matlab.ui.container.Panel
        thenstartcalibratingLabel       matlab.ui.control.Label
        CalibrationperiodsecEditField   matlab.ui.control.NumericEditField
        CalibrationperiodsecEditFieldLabel  matlab.ui.control.Label
        BiasNoiseLabel_2                matlab.ui.control.Label
        NoiseLabel_2                    matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CovarianceMatricesLabel         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        GyroscopeLabel                  matlab.ui.control.Label
        AccelerometerLabel              matlab.ui.control.Label
        BiasNoiseLabel                  matlab.ui.control.Label
        NoiseLabel                      matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        CalibrationStuausEditField      matlab.ui.control.EditField
        CalibrationStuausEditFieldLabel  matlab.ui.control.Label
        StartCalibratingButton          matlab.ui.control.Button
        Placeyourphoneonaflatsurfacefor1minutestraightLabel  matlab.ui.control.Label
        AdvanceButton_2                 matlab.ui.control.Button
        IMUSensorCalibrationLabel       matlab.ui.control.Label
        OutSt_DataPanel                 matlab.ui.container.Panel
        UIAxes_OutStGPS                 matlab.ui.control.UIAxes
        UIAxes_OutStAcc                 matlab.ui.control.UIAxes
        UIAxes_OutStGyro                matlab.ui.control.UIAxes
        UIAxes_OutStAlt                 matlab.ui.control.UIAxes
        UIAxes_OutStGPSAcc              matlab.ui.control.UIAxes
        InSt_mid3_ResultsPanel          matlab.ui.container.Panel
        TrackFeatureRangeUpperlimitEditField  matlab.ui.control.NumericEditField
        TrackFeatureRangeUpperlimitEditFieldLabel  matlab.ui.control.Label
        PausetostartcapturingafterpressingAdvancesecEditField  matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        LoopClosureThresholdEditField   matlab.ui.control.NumericEditField
        LoopClosureThresholdEditFieldLabel  matlab.ui.control.Label
        SkipMaxFramesEditField          matlab.ui.control.NumericEditField
        SkipMaxFramesEditFieldLabel     matlab.ui.control.Label
        ShutterPausebetweenshotssecEditField  matlab.ui.control.NumericEditField
        ShutterPausebetweenshotssecEditFieldLabel  matlab.ui.control.Label
        TrackFeatureRangeLowerlimitEditField  matlab.ui.control.NumericEditField
        TrackFeatureRangeLowerlimitEditFieldLabel  matlab.ui.control.Label
        PropertiescouldbemodifiedLabel  matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        YoucannowmodifymonovslamobjectpropertiesandstartLabel  matlab.ui.control.Label
        AdvanceButton_3                 matlab.ui.control.Button
        SensorsparametersareallsetLabel  matlab.ui.control.Label
        InSt_mid_ResultsPanel           matlab.ui.container.Panel
        ImageHeightEditField            matlab.ui.control.NumericEditField
        ImageHeightEditFieldLabel       matlab.ui.control.Label
        ImageWidthEditField             matlab.ui.control.NumericEditField
        ImageWidthEditFieldLabel        matlab.ui.control.Label
        PyEditField                     matlab.ui.control.NumericEditField
        PyEditFieldLabel                matlab.ui.control.Label
        PxEditField                     matlab.ui.control.NumericEditField
        PxEditFieldLabel                matlab.ui.control.Label
        FyEditField                     matlab.ui.control.NumericEditField
        FyEditFieldLabel                matlab.ui.control.Label
        FxEditField                     matlab.ui.control.NumericEditField
        FxEditFieldLabel                matlab.ui.control.Label
        AdvanceButton                   matlab.ui.control.Button
        kTextArea                       matlab.ui.control.TextArea
        KLabel                          matlab.ui.control.Label
        CalibStatusEditField            matlab.ui.control.EditField
        StoreIntrinsicsparametesButton  matlab.ui.control.Button
        EnterKmatrixasfx0px0fypy001EditField  matlab.ui.control.EditField
        EnterfilelocationEditField      matlab.ui.control.EditField
        EnterfilelocationLabel          matlab.ui.control.Label
        EnterparametersasfollowsFxFyPxPyWHAsLabel  matlab.ui.control.Label
        CalibDropDown                   matlab.ui.control.DropDown
        CameraInterinsicsParametersLabel  matlab.ui.control.Label
        OutLog_DataPanel                matlab.ui.container.Panel
        UIAxes_OutLogGPSAcc             matlab.ui.control.UIAxes
        UIAxes_OutLogAlt                matlab.ui.control.UIAxes
        UIAxes_OutLogGyro               matlab.ui.control.UIAxes
        UIAxes_OutLogAcc                matlab.ui.control.UIAxes
        UIAxes_OutLogGPS                matlab.ui.control.UIAxes
        InSt_DataPanel                  matlab.ui.container.Panel
        UIAxes_InStCam                  matlab.ui.control.UIAxes
        UIAxes_InStGyro                 matlab.ui.control.UIAxes
        UIAxes_InStAcc                  matlab.ui.control.UIAxes
        InStr_OutLog_StatusPanel        matlab.ui.container.Panel
        OutLogStatusLamp                matlab.ui.control.Lamp
        LoggingStatusEditField          matlab.ui.control.EditField
        LoggingStatusEditFieldLabel     matlab.ui.control.Label
        GetLoggeddataButton             matlab.ui.control.Button
        EnterSensorlogmatfilelocationEditField  matlab.ui.control.EditField
        EnterSensorlogmatfilelocationLabel  matlab.ui.control.Label
        OutLogLabel                     matlab.ui.control.Label
        StartPanel                      matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        OutdoorDataDropDown             matlab.ui.control.DropDown
        LocalizationLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        InStr_StatusPanel               matlab.ui.container.Panel
        StreamingStatusLamp             matlab.ui.control.Lamp
        StreamingStatusEditField        matlab.ui.control.EditField
        StreamingStatusEditFieldLabel   matlab.ui.control.Label
        PauseStreamingButton            matlab.ui.control.Button
        StartStreamingButton            matlab.ui.control.Button
        StLabel                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        %Vaiables for positioning
        centerX
        centerY
        appWidth
        appHeight

        LocalizationValue % Description
        DataValue
        OutDataValue
        Timer               timer
        MobileObject         
        StreamedAccelData
        tStreamedAccelData
        StreamedAv
        tStreamedAv
        StreamedAngle
        tStreamedAngle
        StreamedAccelDataOut
        tStreamedAccelDataOut
        StreamedAvOut
        tStreamedAvOut
        StreamedAngleOut
        tStreamedAngleOut
        StreamedPosOut
        tStreamedPosOut
        cam
        shot
        tshot
        latSt
        lonSt
        altSt
        stGPSTStamp
        horizaccSt
        latVectorSt = []
        lonVectorSt = []
        altVectorSt = []
        tGPSVectorSt = []
        horizaccVectorSt = []
        logDir
        loggedData
        calibValue
        calibEntered
        camIntrinsics
        kString = ""
        connectedFlag = 0
        indoorStreamTime
        accelTime
        angvelTime
        bestFitPlot
        loggedPlot
        stBestFitPlot
        streamedPlot

        SampleRate
        imuParams
        calibTimer timer
        calibStartTime
        calibEndTime
        calibTimeSec
        accelData
        gyroData
        accelBiasX
        accelBiasY
        accelBiasZ
        gyroBiasX
        gyroBiasY
        gyroBiasZ
        accelNoiseX
        accelNoiseY
        accelNoiseZ
        accelNoise
        gyroNoiseX
        gyroNoiseY
        gyroNoiseZ
        gyroNoise
        accelCovar
        gyroCovar
        accelBiasCovar
        gyroBiasCovar
        accelBiasStr = ""
        gyroBiasStr = ""
        accelNoiseStr = ""
        gyroNoiseStr = ""

        loggedGPSTime
        bestFitLineHandle
        llaLogged
        loggedENU
        dispDist = 0
        dispTime = 0
        dispVel = 0
        accuracyThreshold = 25
        pointsPerCurve = 3
        degree = 2
        validIndices
        bestFitENU = []
        latChunk
        lonChunk
        pLat
        pLon
        fitPoints
        latFit
        lonFit
        llaChunk
        lats
        lons
        alts
        haccs
        gpsTStamps
        headings
        thetaHeading
        fig

        stGeoAxes
        stFig
        streamedGPSTime
        llaStreamed
        streamedENU
        stDispDist = 0
        stDispTime = 0
        stDispVel = 0
        stBestFitENU = []
        stHeadings
        stThetaHeading
        sizePrev = 0
        sizeNow = 0
        prevLatFit
        prevLonFit

        shutterPause
        VSLAM
        keyFramesNum = 0
        camPositions
        vslamShot
        vslamTShot
        imageCurrent
        prevShotTime
        currShotTime

        accelInRangeTotal
        accelInRange
        angVelInRange
        angVelInRangeTotal
        camPoses
        minLength
        currentPose
        currentAngles
        VSLAMTimer timer
        vslamFig
        vslamAxes


    end
    
    methods (Access = public)
        
        % Callback function to start streaming
        % This function Enables different sensors needed and starts logging 
        function startIndoorStreaming(app)
            app.StreamingStatusEditField.Value = 'Searching for a device...';
            % Create mobiledev object
            while app.connectedFlag == 0
                app.StreamingStatusEditField.Value = 'Connecting to a device...';
                app.MobileObject = mobiledev;
                app.cam = camera(app.MobileObject,'back');
                app.connectedFlag = 1;
            end
            % Enable accelerometer sensor
            app.MobileObject.AccelerationSensorEnabled = 1;
            % Enable Gyroscope sensor
            app.MobileObject.AngularVelocitySensorEnabled = 1;
            % Enable Orientation sensor
            % app.MobileObject.OrientationSensorEnabled = 1;

            % Start logging data
            app.MobileObject.Logging = 1;
            
            % Set up the timer to call the updatePlot method every second

            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
            if isempty(app.cam) & ~isexist(app.cam)
                app.cam = camera(app.MobileObject,'back');
            end   
            app.indoorStreamTime = datetime('now');

            % Create and start the new timer
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 1, ...  
                'TimerFcn', @(~,~)updateStreamingDashboard(app));
            start(app.Timer);

        end


        % Callback function to stop streaming
        function pauseOutdoorStreaming(app)
            % Stop the sensors, timer, and logging
            app.MobileObject.AccelerationSensorEnabled = 0;
            app.MobileObject.AngularVelocitySensorEnabled = 0;
            app.MobileObject.OrientationSensorEnabled = 0;
            app.MobileObject.PositionSensorEnabled = 0;
            app.MobileObject.Logging = 0;

            % Stop and delete the timer
            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end 
        end

        % Callback function to stop streaming
        function pauseIndoorStreaming(app)
            % Stop the sensors, timer, and logging
            app.MobileObject.AccelerationSensorEnabled = 0;
            app.MobileObject.AngularVelocitySensorEnabled = 0;
            app.MobileObject.PositionSensorEnabled = 0;
            app.MobileObject.Logging = 0;

            % Stop and delete the timer
            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
            
            if ~isempty(app.VSLAMTimer) && isvalid(app.VSLAMTimer)
                stop(app.VSLAMTimer);
                delete(app.VSLAMTimer);
            end

            %Stop and delete camera object
            if ~isempty(app.cam)
                app.cam = [];
            end        
        end

        % Callback function to start streaming
        % This function Enables different sensors needed and starts logging 
        function startOutdoorStreaming(app)
            % Create mobiledev object
            if app.connectedFlag == 0
               app.MobileObject = mobiledev; 
               app.connectedFlag = 1;
            end
            app.StreamingStatusEditField.Value = 'Connecting to a device...';
            % Enable accelerometer sensor
            app.MobileObject.AccelerationSensorEnabled = 1;
            % Enable Gyroscope sensor
            app.MobileObject.AngularVelocitySensorEnabled = 1;
            % Enable GPS sensor
            app.MobileObject.PositionSensorEnabled = 1;
            % Enable Orientation Sensor
            %app.MobileObject.OrientationSensorEnabled = 1;

            % Start logging data
            app.MobileObject.Logging = 1;
            app.StreamingStatusEditField.Value = 'Started Streaming';
            
            % Set up the timer to call the updatePlot method every second

            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
            app.stFig = figure('Position', [app.centerX+930, app.centerY-107, 500, app.appHeight-48]);
            app.stGeoAxes = geoaxes(app.stFig);
            geobasemap(app.stGeoAxes, 'streets');
            hold(app.stGeoAxes, 'on');
            % Initialize plots for bestFitENU and loggedENU 
            app.stBestFitPlot = plot(app.UIAxes2_3, NaN, NaN, 'DisplayName', 'Best Fit ENU', 'LineWidth', 1.5);
            app.streamedPlot = plot(app.UIAxes2_3, NaN, NaN, 'DisplayName', 'Streamed ENU', 'LineWidth', 1.5);
            xlabel(app.UIAxes2_3, 'East (m)');
            ylabel(app.UIAxes2_3, 'North (m)');
            legend(app.UIAxes2_3, 'show');
            % Create and start the new timer
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 1, ... 
                'TimerFcn', @(~,~)updateOutdoorStreamingDashboard(app));
            start(app.Timer);

        end


        function startIMUCalib(app)
            % Stop Logging data
            app.MobileObject.Logging = 0;
            app.SampleRate = 100;
            app.MobileObject.SampleRate = app.SampleRate;
            % Create mobiledev object
            if app.connectedFlag == 0
               app.MobileObject = mobiledev; 
               app.connectedFlag = 1;
            end
            % Start logging data
            app.MobileObject.Logging = 1;
            app.calibTimer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'TimerFcn', @(~,~)updatecalibTimer(app));
            start(app.calibTimer);


        end
        function stopIMUCalib(app)
            app.accelData = accellog(app.MobileObject);
            app.gyroData = angvellog(app.MobileObject);


            %Getting Accelerometer parameters (Covariance and Noise Covariance)

            app.accelBiasX = mean(app.accelData(:,1));
            app.accelBiasY = mean(app.accelData(:,2));
            app.accelBiasZ = mean(app.accelData(:,3));

            app.accelNoiseX = app.accelData(:,1) - app.accelBiasX;
            app.accelNoiseY = app.accelData(:,2) - app.accelBiasY;
            app.accelNoiseZ = app.accelData(:,3) - app.accelBiasZ;
            app.accelNoise = [app.accelNoiseX, app.accelNoiseY, app.accelNoiseZ];

            app.accelCovar = cov(app.accelData);
            app.accelBiasCovar = cov(app.accelNoise);

            %Getting Gyroscope parameters (Covariance and Noise Covariance)

            app.gyroBiasX = mean(app.gyroData(:,1));
            app.gyroBiasY = mean(app.gyroData(:,2));
            app.gyroBiasZ = mean(app.gyroData(:,3));

            app.gyroNoiseX = app.gyroData(:,1) - app.gyroBiasX;
            app.gyroNoiseY = app.gyroData(:,2) - app.gyroBiasY;
            app.gyroNoiseZ = app.gyroData(:,3) - app.gyroBiasZ;
            app.gyroNoise = [app.gyroNoiseX, app.gyroNoiseY, app.gyroNoiseZ];

            app.gyroCovar = cov(app.gyroData);
            app.gyroBiasCovar = cov(app.gyroNoise);


            app.imuParams = factorIMUParameters;
            app.imuParams.SampleRate = app.SampleRate;
            app.imuParams.GyroscopeBiasNoise = app.gyroBiasCovar;
            app.imuParams.AccelerometerBiasNoise = app.accelBiasCovar;
            app.imuParams.GyroscopeNoise = app.gyroCovar;
            app.imuParams.AccelerometerNoise = app.accelCovar;
            app.imuParams.ReferenceFrame = "ENU";

            app.StreamingStatusEditField.Value = 'Streaming';
            app.Label_5.Visible = "on";
            app.Label_4.Visible = "on";
            app.Label_3.Visible = "on";
            app.Label_2.Visible = "on";
            app.NoiseLabel_2.Visible = "on";
            app.NoiseLabel.Visible = "on";
            app.BiasNoiseLabel.Visible = "on";
            app.BiasNoiseLabel_2.Visible = "on";
            app.TextArea.Visible = "on";
            app.TextArea_2.Visible = "on";
            app.TextArea_3.Visible = "on";
            app.TextArea_4.Visible = "on";
            app.AccelerometerLabel.Visible = "on";
            app.GyroscopeLabel.Visible = "on";
            app.CovarianceMatricesLabel.Visible = "on";
            app.AdvanceButton_2.Visible = "on";


            for i = 1:size(app.imuParams.GyroscopeBiasNoise, 1)
                rowStr1 = sprintf('%-11.7f',app.imuParams.GyroscopeBiasNoise(i, :));  % Convert row to string with formatting
                app.gyroBiasStr = append(app.gyroBiasStr,rowStr1,newline);             % Append to the main string with newline
            end
            app.TextArea_4.Value = app.gyroBiasStr;

            for i = 1:size(app.imuParams.AccelerometerBiasNoise, 1)
                rowStr2 = sprintf('%-11.7f',app.imuParams.AccelerometerBiasNoise(i, :));  % Convert row to string with formatting
                app.accelBiasStr = append(app.accelBiasStr,rowStr2,newline);             % Append to the main string with newline
            end
            app.TextArea_2.Value = app.accelBiasStr;

            for i = 1:size(app.imuParams.GyroscopeNoise, 1)
                rowStr3 = sprintf('%-11.7f',app.imuParams.GyroscopeNoise(i, :));  % Convert row to string with formatting
                app.gyroNoiseStr = append(app.gyroNoiseStr,rowStr3,newline);             % Append to the main string with newline
            end
            app.TextArea_3.Value = app.gyroNoiseStr;

            for i = 1:size(app.imuParams.AccelerometerNoise, 1)
                rowStr4 = sprintf('%-11.7f',app.imuParams.AccelerometerNoise(i, :));  % Convert row to string with formatting
                app.accelNoiseStr = append(app.accelNoiseStr,rowStr4,newline);             % Append to the main string with newline
            end
            app.TextArea.Value = app.accelNoiseStr;

            startIndoorStreaming(app);
        end

        % Method to update plot
        function updateOutdoorStreamingDashboard(app)
            app.StreamingStatusLamp.Color = 'g';
            % Get accelerometer data and timestamps
            [app.StreamedAccelDataOut, app.tStreamedAccelDataOut] = accellog(app.MobileObject);
            [app.StreamedAvOut,app.tStreamedAvOut] = angvellog(app.MobileObject);

            %Accelerometer Data
            if ~isempty(app.StreamedAccelDataOut)
                plot(app.UIAxes_OutStAcc, app.tStreamedAccelDataOut, app.StreamedAccelDataOut);
                xlabel(app.UIAxes_OutStAcc, 'Time (s)');
                ylabel(app.UIAxes_OutStAcc, 'Acceleration (m/s^2)');
                legend(app.UIAxes_OutStAcc, 'A_x', 'A_y', 'A_z');
            end

            %Gyroscope Data
            if ~isempty(app.StreamedAvOut)
                plot(app.UIAxes_OutStGyro, app.tStreamedAvOut, app.StreamedAvOut);
                xlabel(app.UIAxes_OutStGyro, 'Time (s)');
                ylabel(app.UIAxes_OutStGyro, 'Angular Velocity (rad/s)');
                legend(app.UIAxes_OutStGyro, 'W_x', 'W_y', 'W_z');
            end

            %GPS Data
            [app.latSt, app.lonSt, app.stGPSTStamp, ~, ~, app.altSt, app.horizaccSt] = poslog(app.MobileObject);
            app.StreamingStatusEditField.Value = 'Waiting for GPS data to be recieved.';
            while(isempty(app.latSt))
                [app.latSt, app.lonSt, app.stGPSTStamp, ~, ~, app.altSt, app.horizaccSt] = poslog(app.MobileObject);
            end

            yyaxis(app.UIAxes_OutStGPS, 'left')
            plot(app.UIAxes_OutStGPS, app.stGPSTStamp, app.latSt, 'b');  % Latitude plot in blue
            ylabel(app.UIAxes_OutStGPS, 'Latitude (rad)');
            yyaxis(app.UIAxes_OutStGPS, 'right')
            plot(app.UIAxes_OutStGPS, app.stGPSTStamp, app.lonSt, 'r');  % Longitude plot in red
            ylabel(app.UIAxes_OutStGPS, 'Longitude (rad)');

            plot(app.UIAxes_OutStAlt, app.stGPSTStamp, app.altSt);
            xlabel(app.UIAxes_OutStAlt, 'Time (s)');
            ylabel(app.UIAxes_OutStAlt, 'Altitude (m)');

            plot(app.UIAxes_OutStGPSAcc, app.stGPSTStamp, app.horizaccSt);
            xlabel(app.UIAxes_OutStGPSAcc, 'Time (s)');
            ylabel(app.UIAxes_OutStGPSAcc, 'Horizontal Accuracy (m)');

            app.validIndices = app.horizaccSt <= app.accuracyThreshold;
            app.lats = app.latSt(app.validIndices);
            app.lons = app.lonSt(app.validIndices);
            app.alts = app.altSt(app.validIndices);
            app.haccs = app.horizaccSt(app.validIndices);
            app.gpsTStamps = app.stGPSTStamp(app.validIndices);

            %Update Results panel.
            app.llaStreamed = [app.lats, app.lons, app.alts];
            app.streamedENU = lla2enu(app.llaStreamed,app.llaStreamed(1,:),"ellipsoid");

            hold(app.UIAxes2_3, "on");
            % Plot streamed ENU data in real time
            if isempty(app.streamedPlot) || ~isvalid(app.streamedPlot)
                % Initialize streamed ENU plot
                app.streamedPlot = plot(app.UIAxes2_3, NaN, NaN, 'DisplayName', 'Streamed ENU', 'LineWidth', 1.5);
            end
            set(app.streamedPlot, 'XData', app.streamedENU(:,1), 'YData', app.streamedENU(:,2));
            hold(app.UIAxes2_3, "off");

            if(length(app.lats)<2)
                geoscatter(app.stGeoAxes, app.lats(end), app.lons(end), (app.haccs(end)*10), "blue", "filled", "square");
            else
                geoscatter(app.stGeoAxes, app.lats(end), app.lons(end), (app.haccs(end)), "red", "filled", "square");
            end
            app.sizeNow = size(app.llaStreamed,1);
            % To secure points are updated
            if(size(app.llaStreamed,1) > 1 && app.sizeNow > app.sizePrev)
                app.stHeadings = atan2(diff(app.streamedENU(:,2)), diff(app.streamedENU(:,1)));
                app.stThetaHeading = rad2deg(app.stHeadings(end));
                if (app.stThetaHeading < 0)
                    app.stThetaHeading = app.stThetaHeading + 360;
                end
                app.headingEditField_2.Value = app.stThetaHeading;

                app.stDispTime = double(app.gpsTStamps(end));
                app.TotalTimesecEditField_2.Value = app.stDispTime;

                app.stDispDist = app.stDispDist + sqrt((app.streamedENU(end,1) - app.streamedENU(end-1,1))^2 + (app.streamedENU(end,2) - app.streamedENU(end-1,2))^2);
                app.TotalPlanardistancemEditField_2.Value = app.stDispDist;

                app.stDispVel = app.stDispDist / app.stDispTime;
                app.AveragevelocitymsEditField_2.Value = app.stDispVel;

                app.DifferenceinAltitudemEditField_2.Value = app.alts(end) - app.alts(1);
                app.sizePrev = app.sizeNow;
            end


            if (mod(size(app.llaStreamed,1), app.pointsPerCurve) == 0)
                % Calculate the number of points to fit a curve (for the last chunk, if needed)
                startIdx = size(app.llaStreamed,1) - app.pointsPerCurve +1;

                % Extract the points for curve fitting
                app.latChunk = app.lats(startIdx:end);
                app.lonChunk = app.lons(startIdx:end);

                % Fit a polynomial (adjusted for the number of available points)
                app.pLat = polyfit(1:app.pointsPerCurve, app.latChunk', app.degree);
                app.pLon = polyfit(1:app.pointsPerCurve, app.lonChunk', app.degree);

                % Generate smooth data points for plotting the curve
                app.fitPoints = linspace(1, app.pointsPerCurve, 100);
                app.latFit = polyval(app.pLat, app.fitPoints);
                app.lonFit = polyval(app.pLon, app.fitPoints);


                % Check if there is a previous fit to connect to
                if (size(app.llaStreamed,1) > app.pointsPerCurve)
                    % Plot a connector line between the end of the previous fit and the start of the current fit
                    geoplot([app.prevLatFit(end), app.latFit(1)], [app.prevLonFit(end), app.lonFit(1)], 'blue', 'LineWidth', 5);
                end


                % Plot the fitted curve
                geoplot(app.latFit, app.lonFit, 'b', 'LineWidth', 5);

                % Update the previous fit points to use for the next connector line
                app.prevLatFit = app.latFit;
                app.prevLonFit = app.lonFit;

                % Assuming constant altitude of 0
                app.llaChunk = [app.latFit', app.lonFit', zeros(size(app.latFit))'];  % Create LLA matrix
                app.stBestFitENU = [app.stBestFitENU; lla2enu(app.llaChunk, [app.lats(1), app.lons(1), 0], 'ellipsoid')];

                hold(app.UIAxes2_3, "on");
                % Plot best fit ENU data in real time
                if isempty(app.stBestFitPlot) || ~isvalid(app.stBestFitPlot)
                    % Initialize streamed ENU plot
                    app.stBestFitPlot = plot(app.UIAxes2_3, NaN, NaN, 'DisplayName', 'Best Fit ENU', 'LineWidth', 1.5);
                end
                set(app.stBestFitPlot, 'XData', app.stBestFitENU(:,1), 'YData', app.stBestFitENU(:,2));
                hold(app.UIAxes2_3, "off");

            end

            app.StreamingStatusEditField.Value = 'Streaming';
        end


        % Method to update plot
        function updateStreamingDashboard(app)
            app.StreamingStatusLamp.Color = 'g';
            % Get accelerometer data and timestamps
            [app.StreamedAccelData, app.tStreamedAccelData] = accellog(app.MobileObject);
            [app.StreamedAv,app.tStreamedAv] = angvellog(app.MobileObject);
            app.accelTime = app.indoorStreamTime + seconds(app.tStreamedAccelData);
            app.angvelTime = app.indoorStreamTime + seconds(app.tStreamedAv);

            
            % Check if there's any data to plot

            %Accelerometer Data

            if ~isempty(app.StreamedAccelData)
                % Plot the latest accelerometer data on UIAxes
                plot(app.UIAxes_InStAcc, app.accelTime, app.StreamedAccelData);
                xlabel(app.UIAxes_InStAcc, 'Date');
                ylabel(app.UIAxes_InStAcc, 'Acceleration (m/s^2)');
                legend(app.UIAxes_InStAcc, 'A_x', 'A_y', 'A_z');
            end
            
            %Gyroscope Data

            if ~isempty(app.StreamedAv)
                % Plot the latest accelerometer data on UIAxes
                plot(app.UIAxes_InStGyro, app.angvelTime, app.StreamedAv);
                xlabel(app.UIAxes_InStGyro, 'Date');
                ylabel(app.UIAxes_InStGyro, 'Angular Velocity (rad/s)');
                legend(app.UIAxes_InStGyro, 'W_x', 'W_y', 'W_z');
            end


            app.StreamingStatusEditField.Value = 'Streaming';

            %Camera Data

            [app.shot, app.tshot] = snapshot(app.cam, 'immediate');
            imshow(app.shot, 'Parent', app.UIAxes_InStCam);

        end


        % Callback function to update dashboard with logged data
        function updateLogged(app)

            % Plot the accelerometer data on UIAxes
            plot(app.UIAxes_OutLogAcc, app.loggedData.Acceleration.Timestamp, [app.loggedData.Acceleration.X, app.loggedData.Acceleration.Y, app.loggedData.Acceleration.Z]);
            xlabel(app.UIAxes_OutLogAcc, 'Date');
            ylabel(app.UIAxes_OutLogAcc, 'Linear Acceleration (m/s^2)');
            legend(app.UIAxes_OutLogAcc, 'A_x', 'A_y', 'A_z');

            % Plot the GPS data on UIAxes
            yyaxis(app.UIAxes_OutLogGPS, 'left')
            plot(app.UIAxes_OutLogGPS, app.loggedData.Position.Timestamp, app.loggedData.Position.latitude, 'b');  % Latitude plot in blue
            ylabel(app.UIAxes_OutLogGPS, 'Latitude (rad)');
            yyaxis(app.UIAxes_OutLogGPS, 'right')
            plot(app.UIAxes_OutLogGPS, app.loggedData.Position.Timestamp, app.loggedData.Position.longitude, 'r');  % Longitude plot in red
            ylabel(app.UIAxes_OutLogGPS, 'Longitude (rad)');
            xlabel(app.UIAxes_OutLogGPS, 'Date');


            % Plot the Gyro data on UIAxes
            plot(app.UIAxes_OutLogGyro, app.loggedData.AngularVelocity.Timestamp, [app.loggedData.AngularVelocity.X, app.loggedData.AngularVelocity.Y, app.loggedData.AngularVelocity.Z]);
            xlabel(app.UIAxes_OutLogGyro, 'Date');
            ylabel(app.UIAxes_OutLogGyro, 'Angular Velocity (rad/s)');
            legend(app.UIAxes_OutLogGyro, 'W_x', 'W_y', 'W_z');


            % Plot the Altitude data on UIAxes
            plot(app.UIAxes_OutLogAlt, app.loggedData.Position.Timestamp, app.loggedData.Position.altitude);
            xlabel(app.UIAxes_OutLogAlt, 'Date');
            ylabel(app.UIAxes_OutLogAlt, 'Altitude (m)');

            % Plot the latest accelerometer data on UIAxes
            plot(app.UIAxes_OutLogGPSAcc, app.loggedData.Position.Timestamp, app.loggedData.Position.hacc);
            xlabel(app.UIAxes_OutLogGPSAcc, 'Date');
            ylabel(app.UIAxes_OutLogGPSAcc, 'GPS Accuracy (m)');

            app.validIndices = app.loggedData.Position.hacc <= app.accuracyThreshold;
            app.lats = app.loggedData.Position.latitude(app.validIndices);
            app.lons = app.loggedData.Position.longitude(app.validIndices);
            app.alts = app.loggedData.Position.altitude(app.validIndices);
            app.haccs = app.loggedData.Position.hacc(app.validIndices);
            
            app.gpsTStamps = app.loggedData.Position.Timestamp(app.validIndices);

            % Calculation for ENU position
            app.llaLogged = [app.lats, app.lons, app.alts];
            app.loggedENU = lla2enu(app.llaLogged,app.llaLogged(1,:),"ellipsoid");
            app.headings = atan2(diff(app.loggedENU(:,2)), diff(app.loggedENU(:,1)));
            app.thetaHeading = rad2deg(app.headings);
            app.thetaHeading(app.thetaHeading < 0) = app.thetaHeading + 360;
            app.loggedGPSTime = seconds(diff(app.gpsTStamps));

            app.LoggingStatusEditField.Value = "Logged data are graphed.";
            pause(5);
            app.LoggingStatusEditField.Value = "Logged data are being processed.";

            %Create an interactive new figure outside of app panels.
            app.fig = figure('Position', [app.centerX+930, app.centerY-107, 500, app.appHeight-48]);
            GeoAxes = geoaxes(app.fig);
            geobasemap(GeoAxes, 'streets');
            hold(GeoAxes, 'on');


            geoscatter(GeoAxes, app.lats(1), app.lons(1), (app.haccs(1)*10), "blue", "filled", "square");
            app.bestFitLineHandle = [];

            % Initialize plots for bestFitENU and loggedENU 
            app.bestFitPlot = plot(app.UIAxes2_2, NaN, NaN, 'DisplayName', 'Best Fit ENU', 'LineWidth', 1.5);
            app.loggedPlot = plot(app.UIAxes2_2, NaN, NaN, 'DisplayName', 'Logged ENU', 'LineWidth', 1.5);
            xlabel(app.UIAxes2_2, 'East (m)');
            ylabel(app.UIAxes2_2, 'North (m)');
            legend(app.UIAxes2_2, 'show');

            for i = 2:length(app.lats)
                app.OutLogStatusLamp.Color = [0.93,0.69,0.13];

                % Plot each point individually
                geoscatter(GeoAxes, app.lats(i), app.lons(i), (app.haccs(i)), 'red', 'filled');

                % If we have enough points or if we are at the end with fewer points
                if mod(i, app.pointsPerCurve) == 0 || (i == length(app.lats) && mod(i, app.pointsPerCurve) ~= 0)
                    % Calculate the number of points to fit a curve (for the last chunk, if needed)
                    startIdx = max(1, i - app.pointsPerCurve + 1);
                    numPoints = i - startIdx + 1;  % How many points to fit the curve

                    % Extract the points for curve fitting
                    app.latChunk = app.lats(startIdx:i);
                    app.lonChunk = app.lons(startIdx:i);

                    % Fit a polynomial (adjusted for the number of available points)
                    app.pLat = polyfit(1:numPoints, app.latChunk', app.degree);
                    app.pLon = polyfit(1:numPoints, app.lonChunk', app.degree);

                    % Generate smooth data points for plotting the curve
                    app.fitPoints = linspace(1, numPoints, 100);
                    app.latFit = polyval(app.pLat, app.fitPoints);
                    app.lonFit = polyval(app.pLon, app.fitPoints);


                    % Plot the fitted curve
                    geoplot(app.latFit, app.lonFit, 'b', 'LineWidth', 5);

                    % Assuming constant altitude of 0
                    app.llaChunk = [app.latFit', app.lonFit', zeros(size(app.latFit))'];  % Create LLA matrix
                    app.bestFitENU = [app.bestFitENU; lla2enu(app.llaChunk, [app.lats(1), app.lons(1), 0], 'ellipsoid')];

                    % Connecting the previous curve's last point with current curve's first point
                    if i > app.pointsPerCurve
                        geoplot([app.lats(i-app.pointsPerCurve), app.lats(i-app.pointsPerCurve+1)], ...
                            [app.lons(i-app.pointsPerCurve), app.lons(i-app.pointsPerCurve+1)], 'b', 'LineWidth', 5);
                    end

                end
                hold(app.UIAxes2_2, "on");
                if isempty(app.bestFitPlot) || ~isvalid(app.bestFitPlot)
                    % Initialize app.bestFitPlot with an empty plot and set legend
                    app.bestFitPlot = plot(app.UIAxes2_2, NaN, NaN, 'DisplayName', 'Best Fit ENU', 'LineWidth', 1.5);
                    legend(app.UIAxes2_2, 'show');  % Add legend only once here
                end
                if ~isempty(app.bestFitENU)
                    set(app.bestFitPlot, 'XData', app.bestFitENU(:,1), 'YData', app.bestFitENU(:,2));
                end
                if isempty(app.loggedPlot) || ~isvalid(app.loggedPlot)
                    % Initialize logged ENU plot
                    app.loggedPlot = plot(app.UIAxes2_2, NaN, NaN, 'DisplayName', 'Logged ENU', 'LineWidth', 1.5);
                end
                set(app.loggedPlot, 'XData', app.loggedENU(1:i,1), 'YData', app.loggedENU(1:i,2));
                hold(app.UIAxes2_2, "off");


                % Update and display information
                app.dispDist = app.dispDist + sqrt((app.loggedENU(i,1) - app.loggedENU(i-1,1))^2 + (app.loggedENU(i,2) - app.loggedENU(i-1,2))^2);
                app.dispTime = app.dispTime + app.loggedGPSTime(i-1);
                app.dispVel = app.dispDist / app.dispTime;
                app.headingEditField.Value = app.thetaHeading(i-1);


                app.DifferenceinAltitudemEditField.Value = app.alts(i) - app.alts(1);
                app.AveragevelocitymsEditField.Value = app.dispVel;
                app.TotalTimesecEditField.Value = app.dispTime;
                app.TotalPlanardistancemEditField.Value = app.dispDist;

                %pause(app.loggedGPSTime(i-1));
                pause(10)
            end


            hold(GeoAxes, 'off');
            hold(app.UIAxes2_2, 'off');

            app.LoggingStatusEditField.Value = "Logged data pose estimation done.";

            app.OutLogStatusLamp.Color = "g";

        end
        function updatecalibTimer(app)
            % Check if calibration time is remaining
            if datetime('now') < app.calibEndTime
                % Update the Calibration Status Edit Field with remaining time
                app.CalibrationStuausEditField.Value = sprintf('Time remaining (sec): %d', floor(seconds(app.calibEndTime - datetime('now'))));
            else
                % When the countdown reaches zero, stop the timer
                app.CalibrationStuausEditField.Value = 'Done Calibration';
                stop(app.calibTimer);
                delete(app.calibTimer);
                stopIMUCalib(app);
            end
        end
        function startVslam(app)
            updateStreamingDashboard(app);
            [app.vslamShot, app.vslamTShot] = snapshot(app.cam, 'immediate');

            app.imageCurrent = undistortImage(rgb2gray(app.vslamShot), app.camIntrinsics);

            app.currShotTime = app.vslamTShot;

            app.accelInRangeTotal = app.StreamedAccelData((app.accelTime >= app.prevShotTime) & (app.accelTime <= app.currShotTime), :);

            app.angVelInRangeTotal = app.StreamedAv((app.angvelTime >= app.prevShotTime) & (app.angvelTime <= app.currShotTime), :);
            
            %to ensure they are the same size
            app.minLength = min(length(app.angVelInRangeTotal), length(app.accelInRangeTotal));

            app.accelInRange = app.accelInRangeTotal(1:app.minLength,:);
            app.angVelInRange = app.angVelInRangeTotal(1:app.minLength,:);
            
            
            addFrame(app.VSLAM, app.imageCurrent, app.angVelInRange, app.accelInRange);
            

            if hasNewKeyFrame(app.VSLAM)
                app.keyFramesNum = app.keyFramesNum +1;
            end
            
            app.VSLAMStatusEditField.Value = string(checkStatus(app.VSLAM));
            app.TotalnumberofmappointsEditField.Value = num2str(length(mapPoints(app.VSLAM)));
            app.TotalnumberofkeyframesEditField.Value = num2str(app.keyFramesNum);

            app.camPoses = poses(app.VSLAM);


            if ~isempty(app.camPoses)
                app.currentPose = app.camPoses(length(app.camPoses));
                app.PxmEditField.Value = double(app.currentPose.Translation(1));
                app.PymEditField.Value = double(app.currentPose.Translation(2));
                app.PzmEditField.Value = double(app.currentPose.Translation(3));

                app.currentAngles = rad2deg(rotm2eul(app.currentPose.R));

                app.xdegEditField.Value = double(app.currentAngles(3));
                app.ydegEditField.Value = double(app.currentAngles(2));
                app.zdegEditField.Value = double(app.currentAngles(1));                

            end

            plot(app.VSLAM, 'Parent', app.vslamAxes);
            
            app.prevShotTime = app.currShotTime;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Get screen size
            screenSize = get(0, 'ScreenSize');

            % Define app window size (width and height)
            app.appWidth = app.UIFigure.Position(3);
            app.appHeight = app.UIFigure.Position(4);

            % Calculate the centered position
            app.centerX = floor((screenSize(3) - app.appWidth) / 2);
            app.centerY = floor((screenSize(4) - app.appHeight) / 2);

            % Set the new position of the figure
            app.UIFigure.Position = [app.centerX-220, app.centerY-75, app.appWidth, app.appHeight];


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            delete(app.UIFigure);
            eval([class(app) '()'])
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.LocalizationValue = app.DropDown.Value;
            if (strcmp(app.LocalizationValue, "1- Indoors Live Slam"))
                app.StartPanel.Position = [1,606,1096,88];
                app.InStr_StatusPanel.Visible = "on";
                app.InSt_DataPanel.Visible = "on";
                %app.InSt_ResultsPanel.Visible = "on";
                app.InSt_mid_ResultsPanel.Visible = "on";
                app.DropDown.Visible = "off";
                app.LocalizationLabel.Visible = "on";
                app.LocalizationLabel.Text = "[Indoor Live SLAM]";
            elseif (strcmp(app.LocalizationValue, "2- Outdoor Pose Estimation"))
                app.DropDown.Visible = "off";
                app.OutdoorDataDropDown.Visible = "on";
                app.InStr_StatusPanel.Visible = "off";
                app.InSt_DataPanel.Visible = "off";
                app.InSt_ResultsPanel.Visible = "off";
            end
        end

        % Button pushed function: StartStreamingButton
        function StartStreamingButtonPushed(app, event)
            app.StreamingStatusLamp.Color = [0.93,0.69,0.13];
            app.StreamingStatusEditField.Value = 'Starting to pair with a device ... ';
            if (strcmp(app.LocalizationValue, "1- Indoors Live Slam"))
                startIndoorStreaming(app);
            elseif (strcmp(app.LocalizationValue, "2- Outdoor Pose Estimation"))
                startOutdoorStreaming(app);
            end

        end

        % Button pushed function: PauseStreamingButton
        function PauseStreamingButtonPushed(app, event)
            app.StreamingStatusEditField.Value = 'Pausing the stream...';
            app.StreamingStatusLamp.Color = [0.93,0.69,0.13];
            if (strcmp(app.LocalizationValue, "1- Indoors Live Slam"))
                pauseIndoorStreaming(app);
            elseif (strcmp(app.LocalizationValue, "2- Outdoor Pose Estimation"))
                pauseOutdoorStreaming(app);
            end
            app.StreamingStatusEditField.Value = 'Streaming Paused.';
            app.StreamingStatusLamp.Color = 'r';
        end

        % Value changed function: OutdoorDataDropDown
        function OutdoorDataDropDownValueChanged(app, event)
            app.OutDataValue = app.OutdoorDataDropDown.Value;
            if (strcmp(app.OutDataValue, "1- Logged Data"))
                app.StartPanel.Position = [1,606,1096,88];
                app.InStr_StatusPanel.Visible = "off";
                app.InStr_OutLog_StatusPanel.Visible = "on";
                app.OutdoorDataDropDown.Visible = "off";
                app.OutLog_ResultsPanel.Visible = "on";
                app.OutLog_DataPanel.Visible = "on";
                app.LocalizationLabel.Visible = "on";
                app.LocalizationLabel.Text = "[Outdoor Pose Estimation for Logged data]";
            elseif (strcmp(app.OutDataValue, "2- Streaming Data"))
                app.StartPanel.Position = [1,606,1096,88];
                app.InStr_OutLog_StatusPanel.Visible = "off";
                app.InStr_StatusPanel.Visible = "on";
                app.OutdoorDataDropDown.Visible = "off";
                app.OutSt_ResultsPanel.Visible = "on";
                app.OutSt_DataPanel.Visible = "on";
                app.LocalizationLabel.Visible = "on";
                app.LocalizationLabel.Text = "[Outdoor Live Pose Estimation]";
            end
        end

        % Button pushed function: GetLoggeddataButton
        function GetLoggeddataButtonPushed(app, event)
            app.logDir = app.EnterSensorlogmatfilelocationEditField.Value;
            if isfile(app.logDir)
                app.LoggingStatusEditField.Value = 'Data Obtained.';
                app.OutLogStatusLamp.Color = "g";
                app.loggedData = load(app.logDir);
                pause(1);
                updateLogged(app);
            else
                app.LoggingStatusEditField.Value = 'No existing file, Check carefully!';
                app.OutLogStatusLamp.Color = "r";
            end

        end

        % Value changed function: CalibDropDown
        function CalibDropDownValueChanged(app, event)
            app.calibValue = app.CalibDropDown.Value;
            if (strcmp(app.calibValue, "Load from .mat file locally"))
                app.EnterfilelocationEditField.Visible = "on";
                app.EnterfilelocationLabel.Visible = "on";
                app.ImageHeightEditField.Visible = "off";
                app.ImageHeightEditFieldLabel.Visible = "off";
                app.ImageWidthEditField.Visible = "off";
                app.ImageWidthEditFieldLabel.Visible = "off";
                app.FxEditField.Visible = "off";
                app.FxEditFieldLabel.Visible = "off";
                app.FyEditField.Visible = "off";
                app.FyEditFieldLabel.Visible = "off";
                app.PxEditField.Visible = "off";
                app.PxEditFieldLabel.Visible = "off";
                app.PyEditField.Visible = "off";
                app.PyEditFieldLabel.Visible = "off";
                app.StoreIntrinsicsparametesButton.Visible = "on";
            elseif (strcmp(app.calibValue, "Enter Parameters manually"))
                app.EnterfilelocationEditField.Visible = "off";
                app.EnterfilelocationLabel.Visible = "off";
                app.StoreIntrinsicsparametesButton.Visible = "on";
                app.ImageHeightEditField.Visible = "on";
                app.ImageHeightEditFieldLabel.Visible = "on";
                app.ImageWidthEditField.Visible = "on";
                app.ImageWidthEditFieldLabel.Visible = "on";
                app.FxEditField.Visible = "on";
                app.FxEditFieldLabel.Visible = "on";
                app.FyEditField.Visible = "on";
                app.FyEditFieldLabel.Visible = "on";
                app.PxEditField.Visible = "on";
                app.PxEditFieldLabel.Visible = "on";
                app.PyEditField.Visible = "on";
                app.PyEditFieldLabel.Visible = "on";
            end
        end

        % Button pushed function: StoreIntrinsicsparametesButton
        function StoreIntrinsicsparametesButtonPushed(app, event)
            app.CalibStatusEditField.Visible = "on";
            app.kTextArea.Value = "";
            app.kString = "";
            if (strcmp(app.calibValue, "Load from .mat file locally"))
                if (isfile(app.EnterfilelocationEditField.Value))
                    app.camIntrinsics = load(app.EnterfilelocationEditField.Value).cameraParams.Intrinsics;
                    app.CalibStatusEditField.Value = "Obtained Parameters from file.";
                    app.kTextArea.Visible = "on";
                    app.KLabel.Visible = "on";
                    app.AdvanceButton.Visible = "on";
                    for i = 1:size(app.camIntrinsics.K, 1)
                        rowStr = sprintf('%-22.6f ', app.camIntrinsics.K(i, :));  % Convert row to string with formatting
                        app.kString = append(app.kString,rowStr,newline);             % Append to the main string with newline
                    end
                    app.kTextArea.Value = app.kString;
                else
                    app.CalibStatusEditField.Value = "Invalid file path, check again!";
                    app.kTextArea.Value = "";
                end
            elseif (strcmp(app.calibValue, "Enter Parameters manually"))
                app.CalibStatusEditField.Value = "Parameters entered are stored.";
                app.camIntrinsics = cameraIntrinsics([app.FxEditField.Value, app.FyEditField.Value], ...
                    [app.PxEditField.Value, app.PyEditField.Value], ...
                    [app.ImageHeightEditField.Value, app.ImageWidthEditField.Value]);
                app.kTextArea.Visible = "on";
                app.KLabel.Visible = "on";
                for i = 1:size(app.camIntrinsics.K, 1)
                    rowStr = sprintf('%-22.6f ', app.camIntrinsics.K(i, :));  % Convert row to string with formatting
                    app.kString = append(app.kString,rowStr,newline);             % Append to the main string with newline
                end
                app.kTextArea.Value = app.kString;

                app.kString = "";
                app.AdvanceButton.Visible = "on";
            end
        end

        % Button pushed function: AdvanceButton
        function AdvanceButtonPushed(app, event)
            app.InSt_mid_ResultsPanel.Visible = "off";
            app.InSt_mid2_ResultsPanel.Visible = "on";
        end

        % Button pushed function: StartCalibratingButton
        function StartCalibratingButtonPushed(app, event)
            %and discard logs if streaming started before.
            discardlogs(app.MobileObject);
            app.StreamingStatusEditField.Value = 'Extracting Data for IMU Calibration ... ';
            app.StartCalibratingButton.Visible = "off";
            app.CalibrationStuausEditField.Visible = "on";
            app.CalibrationStuausEditFieldLabel.Visible = "on";
            app.CalibrationStuausEditField.Value = "Calibration starting...";
            pause(2);
            app.calibStartTime = datetime('now');
            app.calibEndTime = app.calibStartTime + seconds(app.CalibrationperiodsecEditField.Value);
            startIMUCalib(app);


        end

        % Button pushed function: AdvanceButton_2
        function AdvanceButton_2Pushed(app, event)
            app.InSt_mid2_ResultsPanel.Visible = "off";
            app.InSt_mid3_ResultsPanel.Visible = "on";
        end

        % Button pushed function: AdvanceButton_3
        function AdvanceButton_3Pushed(app, event)
            app.InSt_mid3_ResultsPanel.Visible = "off";
            app.InSt_ResultsPanel.Visible = "on";
            app.VSLAM = monovslam(app.camIntrinsics, app.imuParams, ...
                "SkipMaxFrames", app.SkipMaxFramesEditField.Value, "TrackFeatureRange", ...
                [app.TrackFeatureRangeLowerlimitEditField.Value app.TrackFeatureRangeUpperlimitEditField.Value], ...
                "LoopClosureThreshold", app.LoopClosureThresholdEditField.Value);
            app.shutterPause = app.ShutterPausebetweenshotssecEditField.Value;
            pause(app.PausetostartcapturingafterpressingAdvancesecEditField.Value);
            [app.vslamShot, app.vslamTShot] = snapshot(app.cam, 'immediate');
            app.prevShotTime = app.vslamTShot;
            app.imageCurrent = undistortImage(app.vslamShot, app.camIntrinsics);
            addFrame(app.VSLAM, app.imageCurrent);
            %{
            % Stop and delete the timer of updating dashboard
            if ~isempty(app.Timer) && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
            %}
            app.vslamFig = figure('Position', [app.centerX+930, app.centerY-107, 500, app.appHeight-48]);
            app.vslamAxes = axes(app.vslamFig);
            hold(app.vslamAxes, "on");


            startIndoorStreaming(app);
            % Create and start the new timer
            app.VSLAMTimer = timer('ExecutionMode', 'fixedRate', ...
                'Period', app.shutterPause, ...
                'TimerFcn', @(~,~)startVslam(app));
            start(app.VSLAMTimer);
            
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fullFilePath = app.EnterfulldestinationpathEditField.Value;

            % Extract the directory part of the path
            [directoryPath, ~, ~] = fileparts(fullFilePath);

            % Check if the directory exists
            if isfolder(directoryPath)
                saveas(app.fig, app.EnterfulldestinationpathEditField.Value);
                app.LoggingStatusEditField.Value = "Figure saved successfully!";
                app.OutLogStatusLamp.Color = "g";
            else
                app.LoggingStatusEditField.Value = "Invalid directory!";
                app.OutLogStatusLamp.Color = "red";
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            fullFilePath = app.EnterfulldestinationpathEditField_2.Value;

            % Extract the directory part of the path
            [directoryPath, ~, ~] = fileparts(fullFilePath);

            % Check if the directory exists
            if isfolder(directoryPath)
                saveas(app.stFig, app.EnterfulldestinationpathEditField_2.Value);
                app.StreamingStatusEditField.Value = "Figure saved successfully!";
                app.StreamingStatusLamp.Color = "g";
            else
                app.StreamingStatusEditField.Value = "Invalid directory!";
                app.StreamingStatusLamp.Color = "red";
            end
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            fullFilePath = app.EnterfulldestinationpathEditField_3.Value;

            % Extract the directory part of the path
            [directoryPath, ~, ~] = fileparts(fullFilePath);

            % Check if the directory exists
            if isfolder(directoryPath)
                saveas(app.vslamFig, app.EnterfulldestinationpathEditField_3.Value);
                app.StreamingStatusEditField.Value = "Figure saved successfully!";
                app.StreamingStatusLamp.Color = "g";
            else
                app.StreamingStatusEditField.Value = "Invalid directory!";
                app.StreamingStatusLamp.Color = "red";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.InStr_StatusPanel = uipanel(app.Panel);
            app.InStr_StatusPanel.Visible = 'off';
            app.StLabel = uilabel(app.InStr_StatusPanel);
            app.StLabel.Text = 'Make Sure Matlab mobile application is opened and (Stream to) option in settings is set to MATLAB';
            app.StartStreamingButton = uibutton(app.InStr_StatusPanel, 'push');
            app.StartStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @StartStreamingButtonPushed, true);
            app.StartStreamingButton.Text = 'Start Streaming';
            app.PauseStreamingButton = uibutton(app.InStr_StatusPanel, 'push');
            app.PauseStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @PauseStreamingButtonPushed, true);
            app.PauseStreamingButton.Text = 'Pause Streaming';
            app.StreamingStatusEditFieldLabel = uilabel(app.InStr_StatusPanel);
            app.StreamingStatusEditFieldLabel.Text = 'Streaming Status:';
            app.StreamingStatusEditField = uieditfield(app.InStr_StatusPanel, 'text');
            app.StreamingStatusEditField.Value = 'Ready to start Streaming ...';
            app.StreamingStatusLamp = uilamp(app.InStr_StatusPanel);
            app.StartPanel = uipanel(app.Panel);
            app.Image = uiimage(app.StartPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.gif');
            app.Label = uilabel(app.StartPanel);
            app.Label.Text = 'Mobile Sensory data dashboard for indoor SLAM and outdoor Pose estimation';
            app.LocalizationLabel = uilabel(app.StartPanel);
            app.LocalizationLabel.Visible = 'off';
            app.OutdoorDataDropDown = uidropdown(app.StartPanel);
            app.OutdoorDataDropDown.Items = {'Data Obtaining Method for Outdoor Localization', '1- Logged Data', '2- Streaming Data'};
            app.OutdoorDataDropDown.ValueChangedFcn = createCallbackFcn(app, @OutdoorDataDropDownValueChanged, true);
            app.OutdoorDataDropDown.Visible = 'off';
            app.OutdoorDataDropDown.Value = 'Data Obtaining Method for Outdoor Localization';
            app.DropDown = uidropdown(app.StartPanel);
            app.DropDown.Items = {'Choose Localization Method', '1- Indoors Live Slam', '2- Outdoor Pose Estimation'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Choose Localization Method';
            app.ResetButton = uibutton(app.StartPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InStr_OutLog_StatusPanel = uipanel(app.Panel);
            app.InStr_OutLog_StatusPanel.Visible = 'off';
            app.OutLogLabel = uilabel(app.InStr_OutLog_StatusPanel);
            app.OutLogLabel.Text = 'Make Sure Matlab-Drive is connected locally, and Logged data contain the following data (Linear Acceleration, Angular Velocity, and GPS Position)';
            app.EnterSensorlogmatfilelocationLabel = uilabel(app.InStr_OutLog_StatusPanel);
            app.EnterSensorlogmatfilelocationLabel.Text = 'Enter Sensorlog (.mat) file location: ';
            app.EnterSensorlogmatfilelocationEditField = uieditfield(app.InStr_OutLog_StatusPanel, 'text');
            app.GetLoggeddataButton = uibutton(app.InStr_OutLog_StatusPanel, 'push');
            app.GetLoggeddataButton.ButtonPushedFcn = createCallbackFcn(app, @GetLoggeddataButtonPushed, true);
            app.GetLoggeddataButton.Text = 'Get Logged data';
            app.LoggingStatusEditFieldLabel = uilabel(app.InStr_OutLog_StatusPanel);
            app.LoggingStatusEditFieldLabel.Text = 'Logging Status:';
            app.LoggingStatusEditField = uieditfield(app.InStr_OutLog_StatusPanel, 'text');
            app.LoggingStatusEditField.Value = 'Ready to extract logged data ...';
            app.OutLogStatusLamp = uilamp(app.InStr_OutLog_StatusPanel);
            app.InSt_DataPanel = uipanel(app.Panel);
            app.InSt_DataPanel.Title = 'Live Streamed Data';
            app.InSt_DataPanel.Visible = 'off';
            app.UIAxes_InStAcc = uiaxes(app.InSt_DataPanel);
            title(app.UIAxes_InStAcc, 'Linear Acceleration in local frame')
            xlabel(app.UIAxes_InStAcc, 'Time (s)')
            ylabel(app.UIAxes_InStAcc, 'Acceleration (m/s^2)')
            app.UIAxes_InStGyro = uiaxes(app.InSt_DataPanel);
            title(app.UIAxes_InStGyro, 'Angular Velocity in local frame')
            xlabel(app.UIAxes_InStGyro, 'Time (s)')
            ylabel(app.UIAxes_InStGyro, 'Angular Velocity (rad/s)')
            app.UIAxes_InStCam = uiaxes(app.InSt_DataPanel);
            title(app.UIAxes_InStCam, 'Camera')
            app.UIAxes_InStCam.XTick = [];
            app.UIAxes_InStCam.YTick = [];
            app.OutLog_DataPanel = uipanel(app.Panel);
            app.OutLog_DataPanel.Title = 'Logged Data';
            app.OutLog_DataPanel.Visible = 'off';
            app.UIAxes_OutLogGPS = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogGPS, 'GPS (Latitude / Longitude)')
            xlabel(app.UIAxes_OutLogGPS, 'Time (s)')
            ylabel(app.UIAxes_OutLogGPS, 'GPS Lat/Lon (deg)')
            zlabel(app.UIAxes_OutLogGPS, 'Z')
            app.UIAxes_OutLogAcc = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogAcc, 'Linear Acceleration in local frame')
            xlabel(app.UIAxes_OutLogAcc, 'Time (s)')
            ylabel(app.UIAxes_OutLogAcc, 'Linear Acceleration (m/s^2)')
            zlabel(app.UIAxes_OutLogAcc, 'Z')
            app.UIAxes_OutLogGyro = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogGyro, 'Angular Velocity in local frame')
            xlabel(app.UIAxes_OutLogGyro, 'Time (s)')
            ylabel(app.UIAxes_OutLogGyro, 'Angular Velocity (rad/s)')
            zlabel(app.UIAxes_OutLogGyro, 'Z')
            app.UIAxes_OutLogAlt = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogAlt, 'GPS (Altitude)')
            xlabel(app.UIAxes_OutLogAlt, 'Time (s)')
            ylabel(app.UIAxes_OutLogAlt, 'Latitude (m)')
            zlabel(app.UIAxes_OutLogAlt, 'Z')
            app.UIAxes_OutLogGPSAcc = uiaxes(app.OutLog_DataPanel);
            title(app.UIAxes_OutLogGPSAcc, 'GPS Horizontal Accuracy ')
            xlabel(app.UIAxes_OutLogGPSAcc, 'Time (s)')
            ylabel(app.UIAxes_OutLogGPSAcc, 'GPS Accuracy (m)')
            zlabel(app.UIAxes_OutLogGPSAcc, 'Z')
            app.InSt_mid_ResultsPanel = uipanel(app.Panel);
            app.InSt_mid_ResultsPanel.Title = 'Indoor Live SLAM';
            app.InSt_mid_ResultsPanel.Visible = 'off';
            app.CameraInterinsicsParametersLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.CameraInterinsicsParametersLabel.Text = 'Camera Interinsics Parameters';
            app.CalibDropDown = uidropdown(app.InSt_mid_ResultsPanel);
            app.CalibDropDown.Items = {'Method to get Intrinsics camera Params', 'Load from .mat file locally', 'Enter Parameters manually'};
            app.CalibDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibDropDownValueChanged, true);
            app.CalibDropDown.Value = 'Method to get Intrinsics camera Params';
            app.EnterparametersasfollowsFxFyPxPyWHAsLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.EnterparametersasfollowsFxFyPxPyWHAsLabel.Visible = 'off';
            app.EnterparametersasfollowsFxFyPxPyWHAsLabel.Text = 'Enter the following parameters:';
            app.EnterfilelocationLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.EnterfilelocationLabel.Visible = 'off';
            app.EnterfilelocationLabel.Text = 'Enter file location:';
            app.EnterfilelocationEditField = uieditfield(app.InSt_mid_ResultsPanel, 'text');
            app.EnterfilelocationEditField.Visible = 'off';
            app.EnterKmatrixasfx0px0fypy001EditField = uieditfield(app.InSt_mid_ResultsPanel, 'text');
            app.EnterKmatrixasfx0px0fypy001EditField.Visible = 'off';
            app.StoreIntrinsicsparametesButton = uibutton(app.InSt_mid_ResultsPanel, 'push');
            app.StoreIntrinsicsparametesButton.ButtonPushedFcn = createCallbackFcn(app, @StoreIntrinsicsparametesButtonPushed, true);
            app.StoreIntrinsicsparametesButton.Visible = 'off';
            app.StoreIntrinsicsparametesButton.Text = 'Store Intrinsics parametes';
            app.CalibStatusEditField = uieditfield(app.InSt_mid_ResultsPanel, 'text');
            app.CalibStatusEditField.Visible = 'off';
            app.KLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.KLabel.Visible = 'off';
            app.KLabel.Text = 'K = ';
            app.kTextArea = uitextarea(app.InSt_mid_ResultsPanel);
            app.kTextArea.Visible = 'off';
            app.AdvanceButton = uibutton(app.InSt_mid_ResultsPanel, 'push');
            app.AdvanceButton.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButtonPushed, true);
            app.AdvanceButton.Visible = 'off';
            app.AdvanceButton.Text = 'Advance';
            app.FxEditFieldLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.FxEditFieldLabel.Visible = 'off';
            app.FxEditFieldLabel.Text = 'Fx';
            app.FxEditField = uieditfield(app.InSt_mid_ResultsPanel, 'numeric');
            app.FxEditField.Visible = 'off';
            app.FyEditFieldLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.FyEditFieldLabel.Visible = 'off';
            app.FyEditFieldLabel.Text = 'Fy';
            app.FyEditField = uieditfield(app.InSt_mid_ResultsPanel, 'numeric');
            app.FyEditField.Visible = 'off';
            app.PxEditFieldLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.PxEditFieldLabel.Visible = 'off';
            app.PxEditFieldLabel.Text = 'Px';
            app.PxEditField = uieditfield(app.InSt_mid_ResultsPanel, 'numeric');
            app.PxEditField.Visible = 'off';
            app.PyEditFieldLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.PyEditFieldLabel.Visible = 'off';
            app.PyEditFieldLabel.Text = 'Py';
            app.PyEditField = uieditfield(app.InSt_mid_ResultsPanel, 'numeric');
            app.PyEditField.Visible = 'off';
            app.ImageWidthEditFieldLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.ImageWidthEditFieldLabel.Visible = 'off';
            app.ImageWidthEditFieldLabel.Text = 'Image Width';
            app.ImageWidthEditField = uieditfield(app.InSt_mid_ResultsPanel, 'numeric');
            app.ImageWidthEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel = uilabel(app.InSt_mid_ResultsPanel);
            app.ImageHeightEditFieldLabel.Visible = 'off';
            app.ImageHeightEditFieldLabel.Text = 'Image Height';
            app.ImageHeightEditField = uieditfield(app.InSt_mid_ResultsPanel, 'numeric');
            app.ImageHeightEditField.Visible = 'off';
            app.InSt_mid3_ResultsPanel = uipanel(app.Panel);
            app.InSt_mid3_ResultsPanel.Title = 'Indoor Live SLAM';
            app.InSt_mid3_ResultsPanel.Visible = 'off';
            app.SensorsparametersareallsetLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.SensorsparametersareallsetLabel.Text = 'Sensors'' parameters are all set.';
            app.AdvanceButton_3 = uibutton(app.InSt_mid3_ResultsPanel, 'push');
            app.AdvanceButton_3.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButton_3Pushed, true);
            app.AdvanceButton_3.Text = 'Advance';
            app.YoucannowmodifymonovslamobjectpropertiesandstartLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.YoucannowmodifymonovslamobjectpropertiesandstartLabel.Text = 'You can now modify monovslam object properties and start.';
            app.Label_12 = uilabel(app.InSt_mid3_ResultsPanel);
            app.Label_12.Text = '_________________________________________________________';
            app.PropertiescouldbemodifiedLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.PropertiescouldbemodifiedLabel.Text = 'Properties could be modified:';
            app.TrackFeatureRangeLowerlimitEditFieldLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.TrackFeatureRangeLowerlimitEditFieldLabel.Text = 'TrackFeatureRange (Lower limit)';
            app.TrackFeatureRangeLowerlimitEditField = uieditfield(app.InSt_mid3_ResultsPanel, 'numeric');
            app.TrackFeatureRangeLowerlimitEditField.Value = 30;
            app.ShutterPausebetweenshotssecEditFieldLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.ShutterPausebetweenshotssecEditFieldLabel.Text = 'Shutter Pause between shots (sec):';
            app.ShutterPausebetweenshotssecEditField = uieditfield(app.InSt_mid3_ResultsPanel, 'numeric');
            app.ShutterPausebetweenshotssecEditField.Value = 1;
            app.SkipMaxFramesEditFieldLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.SkipMaxFramesEditFieldLabel.Text = 'SkipMaxFrames';
            app.SkipMaxFramesEditField = uieditfield(app.InSt_mid3_ResultsPanel, 'numeric');
            app.SkipMaxFramesEditField.Value = 20;
            app.LoopClosureThresholdEditFieldLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.LoopClosureThresholdEditFieldLabel.Text = 'LoopClosureThreshold';
            app.LoopClosureThresholdEditField = uieditfield(app.InSt_mid3_ResultsPanel, 'numeric');
            app.LoopClosureThresholdEditField.Value = 60;
            app.Label_14 = uilabel(app.InSt_mid3_ResultsPanel);
            app.Label_14.Text = '_________________________________________________________';
            app.EditFieldLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.EditFieldLabel.Text = 'Pause to start capturing after pressing Advance (sec):';
            app.PausetostartcapturingafterpressingAdvancesecEditField = uieditfield(app.InSt_mid3_ResultsPanel, 'numeric');
            app.PausetostartcapturingafterpressingAdvancesecEditField.Value = 10;
            app.TrackFeatureRangeUpperlimitEditFieldLabel = uilabel(app.InSt_mid3_ResultsPanel);
            app.TrackFeatureRangeUpperlimitEditFieldLabel.Text = 'TrackFeatureRange (Upper limit)';
            app.TrackFeatureRangeUpperlimitEditField = uieditfield(app.InSt_mid3_ResultsPanel, 'numeric');
            app.TrackFeatureRangeUpperlimitEditField.Value = 100;
            app.OutSt_DataPanel = uipanel(app.Panel);
            app.OutSt_DataPanel.Title = 'Live Data';
            app.OutSt_DataPanel.Visible = 'off';
            app.UIAxes_OutStGPSAcc = uiaxes(app.OutSt_DataPanel);
            title(app.UIAxes_OutStGPSAcc, 'GPS Horizontal Accuracy ')
            xlabel(app.UIAxes_OutStGPSAcc, 'Time (s)')
            ylabel(app.UIAxes_OutStGPSAcc, 'GPS Accuracy (m)')
            zlabel(app.UIAxes_OutStGPSAcc, 'Z')
            app.UIAxes_OutStAlt = uiaxes(app.OutSt_DataPanel);
            title(app.UIAxes_OutStAlt, 'GPS (Altitude)')
            xlabel(app.UIAxes_OutStAlt, 'Time (s)')
            ylabel(app.UIAxes_OutStAlt, 'Latitude (m)')
            zlabel(app.UIAxes_OutStAlt, 'Z')
            app.UIAxes_OutStGyro = uiaxes(app.OutSt_DataPanel);
            title(app.UIAxes_OutStGyro, 'Angular Velocity in local frame ')
            xlabel(app.UIAxes_OutStGyro, 'Time (s)')
            ylabel(app.UIAxes_OutStGyro, 'Angular Velocity (rad/s)')
            zlabel(app.UIAxes_OutStGyro, 'Z')
            app.UIAxes_OutStAcc = uiaxes(app.OutSt_DataPanel);
            title(app.UIAxes_OutStAcc, 'Linear Acceleration in local frame ')
            xlabel(app.UIAxes_OutStAcc, 'Time (s)')
            ylabel(app.UIAxes_OutStAcc, 'Linear Acceleration (m/s^2)')
            zlabel(app.UIAxes_OutStAcc, 'Z')
            app.UIAxes_OutStGPS = uiaxes(app.OutSt_DataPanel);
            title(app.UIAxes_OutStGPS, 'GPS (Latitude / Longitude)')
            xlabel(app.UIAxes_OutStGPS, 'Time (s)')
            ylabel(app.UIAxes_OutStGPS, 'GPS Lat/Lon (deg)')
            zlabel(app.UIAxes_OutStGPS, 'Z')
            app.InSt_mid2_ResultsPanel = uipanel(app.Panel);
            app.InSt_mid2_ResultsPanel.Title = 'Indoor Live SLAM';
            app.InSt_mid2_ResultsPanel.Visible = 'off';
            app.IMUSensorCalibrationLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.IMUSensorCalibrationLabel.Text = 'IMU Sensor Calibration';
            app.AdvanceButton_2 = uibutton(app.InSt_mid2_ResultsPanel, 'push');
            app.AdvanceButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButton_2Pushed, true);
            app.AdvanceButton_2.Visible = 'off';
            app.AdvanceButton_2.Text = 'Advance';
            app.Placeyourphoneonaflatsurfacefor1minutestraightLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.Placeyourphoneonaflatsurfacefor1minutestraightLabel.Text = 'Make sure streaming is on, place your phone on a flat surface';
            app.StartCalibratingButton = uibutton(app.InSt_mid2_ResultsPanel, 'push');
            app.StartCalibratingButton.ButtonPushedFcn = createCallbackFcn(app, @StartCalibratingButtonPushed, true);
            app.StartCalibratingButton.Text = 'Start Calibrating.';
            app.CalibrationStuausEditFieldLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.CalibrationStuausEditFieldLabel.Visible = 'off';
            app.CalibrationStuausEditFieldLabel.Text = 'Calibration  Stuaus:';
            app.CalibrationStuausEditField = uieditfield(app.InSt_mid2_ResultsPanel, 'text');
            app.CalibrationStuausEditField.Visible = 'off';
            app.TextArea = uitextarea(app.InSt_mid2_ResultsPanel);
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.InSt_mid2_ResultsPanel);
            app.TextArea_2.Visible = 'off';
            app.NoiseLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.NoiseLabel.Visible = 'off';
            app.NoiseLabel.Text = 'Noise';
            app.BiasNoiseLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.BiasNoiseLabel.Visible = 'off';
            app.BiasNoiseLabel.Text = 'Bias Noise';
            app.AccelerometerLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.AccelerometerLabel.Visible = 'off';
            app.AccelerometerLabel.Text = 'Accelerometer';
            app.GyroscopeLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.GyroscopeLabel.Visible = 'off';
            app.GyroscopeLabel.Text = 'Gyroscope';
            app.Label_2 = uilabel(app.InSt_mid2_ResultsPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '_____________________';
            app.CovarianceMatricesLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.CovarianceMatricesLabel.Visible = 'off';
            app.CovarianceMatricesLabel.Text = 'Covariance Matrices';
            app.Label_3 = uilabel(app.InSt_mid2_ResultsPanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '_____________________';
            app.Label_4 = uilabel(app.InSt_mid2_ResultsPanel);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '_______________';
            app.Label_5 = uilabel(app.InSt_mid2_ResultsPanel);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '____________';
            app.TextArea_3 = uitextarea(app.InSt_mid2_ResultsPanel);
            app.TextArea_3.Visible = 'off';
            app.TextArea_4 = uitextarea(app.InSt_mid2_ResultsPanel);
            app.TextArea_4.Visible = 'off';
            app.NoiseLabel_2 = uilabel(app.InSt_mid2_ResultsPanel);
            app.NoiseLabel_2.Visible = 'off';
            app.NoiseLabel_2.Text = 'Noise';
            app.BiasNoiseLabel_2 = uilabel(app.InSt_mid2_ResultsPanel);
            app.BiasNoiseLabel_2.Visible = 'off';
            app.BiasNoiseLabel_2.Text = 'Bias Noise';
            app.CalibrationperiodsecEditFieldLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.CalibrationperiodsecEditFieldLabel.Text = 'Calibration period (sec):';
            app.CalibrationperiodsecEditField = uieditfield(app.InSt_mid2_ResultsPanel, 'numeric');
            app.CalibrationperiodsecEditField.Value = 120;
            app.thenstartcalibratingLabel = uilabel(app.InSt_mid2_ResultsPanel);
            app.thenstartcalibratingLabel.Text = ' then start calibrating.';
            app.OutLog_ResultsPanel = uipanel(app.Panel);
            app.OutLog_ResultsPanel.Title = 'Outdoor Logged data pose estimation';
            app.OutLog_ResultsPanel.Visible = 'off';
            app.UIAxes2_2 = uiaxes(app.OutLog_ResultsPanel);
            title(app.UIAxes2_2, 'Local Trajectory')
            xlabel(app.UIAxes2_2, 'East')
            ylabel(app.UIAxes2_2, 'North')
            zlabel(app.UIAxes2_2, 'Z')
            app.Label_8 = uilabel(app.OutLog_ResultsPanel);
            app.Label_8.Text = '_________________________';
            app.Label_9 = uilabel(app.OutLog_ResultsPanel);
            app.Label_9.Text = '_________________________';
            app.DistanceLabel = uilabel(app.OutLog_ResultsPanel);
            app.DistanceLabel.Text = 'Distance';
            app.TotalPlanardistancemLabel = uilabel(app.OutLog_ResultsPanel);
            app.TotalPlanardistancemLabel.Text = '-Total Planar distance (m)';
            app.TotalPlanardistancemEditField = uieditfield(app.OutLog_ResultsPanel, 'numeric');
            app.TotalTimesecLabel = uilabel(app.OutLog_ResultsPanel);
            app.TotalTimesecLabel.Text = '-Total Time (sec)';
            app.TotalTimesecEditField = uieditfield(app.OutLog_ResultsPanel, 'numeric');
            app.AveragevelocitymsLabel = uilabel(app.OutLog_ResultsPanel);
            app.AveragevelocitymsLabel.Text = '-Average velocity (m/s)';
            app.AveragevelocitymsEditField = uieditfield(app.OutLog_ResultsPanel, 'numeric');
            app.DifferenceinAltitudemLabel = uilabel(app.OutLog_ResultsPanel);
            app.DifferenceinAltitudemLabel.Text = '-Difference in Altitude (m)';
            app.DifferenceinAltitudemEditField = uieditfield(app.OutLog_ResultsPanel, 'numeric');
            app.CurrentHeadingangleClockwiseLabel = uilabel(app.OutLog_ResultsPanel);
            app.CurrentHeadingangleClockwiseLabel.Text = '-Current Heading angle Clockwise ';
            app.fromNorthdegLabel = uilabel(app.OutLog_ResultsPanel);
            app.fromNorthdegLabel.Text = 'from North (deg)';
            app.headingEditField = uieditfield(app.OutLog_ResultsPanel, 'numeric');
            app.Label_17 = uilabel(app.OutLog_ResultsPanel);
            app.Label_17.Text = '____________________';
            app.Label_18 = uilabel(app.OutLog_ResultsPanel);
            app.Label_18.Text = '_____________________';
            app.SaveGeoAxesLocallyLabel = uilabel(app.OutLog_ResultsPanel);
            app.SaveGeoAxesLocallyLabel.Text = 'Save GeoAxes Locally';
            app.EnterfulldestinationpathEditFieldLabel = uilabel(app.OutLog_ResultsPanel);
            app.EnterfulldestinationpathEditFieldLabel.Text = 'Enter full destination path :';
            app.EnterfulldestinationpathEditField = uieditfield(app.OutLog_ResultsPanel, 'text');
            app.EnterfulldestinationpathEditField.Value = '/home/GeoAxes.png';
            app.SaveButton = uibutton(app.OutLog_ResultsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutSt_ResultsPanel = uipanel(app.Panel);
            app.OutSt_ResultsPanel.Title = 'Outdoor Live Pose Estimation';
            app.OutSt_ResultsPanel.Visible = 'off';
            app.UIAxes2_3 = uiaxes(app.OutSt_ResultsPanel);
            title(app.UIAxes2_3, 'Local Trajectory')
            xlabel(app.UIAxes2_3, 'East')
            ylabel(app.UIAxes2_3, 'North')
            zlabel(app.UIAxes2_3, 'Z')
            app.headingEditField_2 = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.fromNorthdegLabel_2 = uilabel(app.OutSt_ResultsPanel);
            app.fromNorthdegLabel_2.Text = 'from North (deg)';
            app.CurrentHeadingangleClockwiseLabel_2 = uilabel(app.OutSt_ResultsPanel);
            app.CurrentHeadingangleClockwiseLabel_2.Text = '-Current Heading angle Clockwise ';
            app.DistanceLabel_2 = uilabel(app.OutSt_ResultsPanel);
            app.DistanceLabel_2.Text = 'Distance';
            app.Label_11 = uilabel(app.OutSt_ResultsPanel);
            app.Label_11.Text = '_________________________';
            app.Label_10 = uilabel(app.OutSt_ResultsPanel);
            app.Label_10.Text = '_________________________';
            app.TotalPlanardistancemEditField_2Label = uilabel(app.OutSt_ResultsPanel);
            app.TotalPlanardistancemEditField_2Label.Text = '-Total Planar distance (m)';
            app.TotalPlanardistancemEditField_2 = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.TotalTimesecEditField_2Label = uilabel(app.OutSt_ResultsPanel);
            app.TotalTimesecEditField_2Label.Text = '-Total Time (sec)';
            app.TotalTimesecEditField_2 = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.AveragevelocitymsEditField_2Label = uilabel(app.OutSt_ResultsPanel);
            app.AveragevelocitymsEditField_2Label.Text = '-Average velocity (m/s)';
            app.AveragevelocitymsEditField_2 = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.DifferenceinAltitudemEditField_2Label = uilabel(app.OutSt_ResultsPanel);
            app.DifferenceinAltitudemEditField_2Label.Text = '-Difference in Altitude (m)';
            app.DifferenceinAltitudemEditField_2 = uieditfield(app.OutSt_ResultsPanel, 'numeric');
            app.Label_19 = uilabel(app.OutSt_ResultsPanel);
            app.Label_19.Text = '____________________';
            app.SaveGeoAxesLocallyLabel_2 = uilabel(app.OutSt_ResultsPanel);
            app.SaveGeoAxesLocallyLabel_2.Text = 'Save GeoAxes Locally';
            app.SaveButton_2 = uibutton(app.OutSt_ResultsPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.EnterfulldestinationpathEditField_2Label = uilabel(app.OutSt_ResultsPanel);
            app.EnterfulldestinationpathEditField_2Label.Text = 'Enter full destination path :';
            app.EnterfulldestinationpathEditField_2 = uieditfield(app.OutSt_ResultsPanel, 'text');
            app.EnterfulldestinationpathEditField_2.Value = '/home/GeoAxes.png';
            app.Label_20 = uilabel(app.OutSt_ResultsPanel);
            app.Label_20.Text = '_____________________';
            app.InSt_ResultsPanel = uipanel(app.Panel);
            app.InSt_ResultsPanel.Title = 'Indoor Live SLAM';
            app.InSt_ResultsPanel.Visible = 'off';
            app.VSLAMStatusEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.VSLAMStatusEditFieldLabel.Text = 'VSLAM Status:';
            app.VSLAMStatusEditField = uieditfield(app.InSt_ResultsPanel, 'text');
            app.TotalnumberofkeyframesEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.TotalnumberofkeyframesEditFieldLabel.Text = 'Total number of key frames:';
            app.TotalnumberofkeyframesEditField = uieditfield(app.InSt_ResultsPanel, 'text');
            app.TotalnumberofmappointsEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.TotalnumberofmappointsEditFieldLabel.Text = 'Total number of map points:';
            app.TotalnumberofmappointsEditField = uieditfield(app.InSt_ResultsPanel, 'text');
            app.Label_6 = uilabel(app.InSt_ResultsPanel);
            app.Label_6.Text = '______________________';
            app.Label_7 = uilabel(app.InSt_ResultsPanel);
            app.Label_7.Text = '_______________________';
            app.CurrentPoseLabel = uilabel(app.InSt_ResultsPanel);
            app.CurrentPoseLabel.Text = 'Current Pose';
            app.PxmEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.PxmEditFieldLabel.Text = 'Px (m)';
            app.PxmEditField = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.PymEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.PymEditFieldLabel.Text = 'Py (m)';
            app.PymEditField = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.PzmEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.PzmEditFieldLabel.Text = 'Pz (m)';
            app.PzmEditField = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.xdegEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.xdegEditFieldLabel.Text = 'θx (deg)';
            app.xdegEditField = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.ydegEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.ydegEditFieldLabel.Text = 'θy (deg)';
            app.ydegEditField = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.zdegEditFieldLabel = uilabel(app.InSt_ResultsPanel);
            app.zdegEditFieldLabel.Text = 'θz (deg)';
            app.zdegEditField = uieditfield(app.InSt_ResultsPanel, 'numeric');
            app.Label_15 = uilabel(app.InSt_ResultsPanel);
            app.Label_15.Text = '______________________';
            app.Label_16 = uilabel(app.InSt_ResultsPanel);
            app.Label_16.Text = '______________________';
            app.ReferenceframeLabel = uilabel(app.InSt_ResultsPanel);
            app.ReferenceframeLabel.Text = 'Reference frame';
            app.Image2 = uiimage(app.InSt_ResultsPanel);
            app.Image2.ImageSource = 'refFrame.png';
            app.CurrentPoseisreferencedtoLabel = uilabel(app.InSt_ResultsPanel);
            app.CurrentPoseisreferencedtoLabel.Text = 'Current Pose is referenced to ';
            app.firstkeyframewithreferenceLabel = uilabel(app.InSt_ResultsPanel);
            app.firstkeyframewithreferenceLabel.Text = 'first key frame with reference ';
            app.axisasshownLabel = uilabel(app.InSt_ResultsPanel);
            app.axisasshownLabel.Text = 'axis as shown.';
            app.Label_21 = uilabel(app.InSt_ResultsPanel);
            app.Label_21.Text = '___________________';
            app.Label_22 = uilabel(app.InSt_ResultsPanel);
            app.Label_22.Text = '___________________';
            app.SavingFeaturesMapLabel = uilabel(app.InSt_ResultsPanel);
            app.SavingFeaturesMapLabel.Text = 'Saving Features Map';
            app.SaveButton_3 = uibutton(app.InSt_ResultsPanel, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.EnterfulldestinationpathEditField_3Label = uilabel(app.InSt_ResultsPanel);
            app.EnterfulldestinationpathEditField_3Label.Text = 'Enter full destination path :';
            app.EnterfulldestinationpathEditField_3 = uieditfield(app.InSt_ResultsPanel, 'text');
            app.EnterfulldestinationpathEditField_3.Value = '/home/featuresMap.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLAM_PoseEstimation_Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end